classdef Suspension < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuspensionSetupUIFigure         matlab.ui.Figure
        SteeringPanel                   matlab.ui.container.Panel
        AckermannEditField              matlab.ui.control.NumericEditField
        AckermannEditFieldLabel         matlab.ui.control.Label
        PiniondiamatermmEditField       matlab.ui.control.NumericEditField
        PiniondiamatermmEditFieldLabel  matlab.ui.control.Label
        SteeringWheelangleEditField     matlab.ui.control.NumericEditField
        SteeringWheelangleEditFieldLabel  matlab.ui.control.Label
        SteeringLeverLengthmmEditField  matlab.ui.control.NumericEditField
        SteeringLeverLengthmmEditFieldLabel  matlab.ui.control.Label
        SteeringWheelRadiusmmEditField  matlab.ui.control.NumericEditField
        SteeringWheelRadiusmmEditFieldLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        BrakesPanel                     matlab.ui.container.Panel
        ABSPanel                        matlab.ui.container.Panel
        ABSSwitch                       matlab.ui.control.Switch
        BrakeBiasFrontEditField         matlab.ui.control.NumericEditField
        BrakeBiasFrontEditFieldLabel    matlab.ui.control.Label
        BrakeForceNEditField            matlab.ui.control.NumericEditField
        BrakeForceNEditFieldLabel       matlab.ui.control.Label
        InnereffectivebrakediscdiametermmEditField  matlab.ui.control.NumericEditField
        InnereffectivebrakediscdiametermmEditFieldLabel  matlab.ui.control.Label
        OutereffectivebrakediscdiametermmEditField  matlab.ui.control.NumericEditField
        OutereffectivebrakediscdiametermmEditFieldLabel  matlab.ui.control.Label
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        TyreParametersPanel             matlab.ui.container.Panel
        RBX3EditField                   matlab.ui.control.NumericEditField
        RBX3EditFieldLabel              matlab.ui.control.Label
        LYKAEditField                   matlab.ui.control.NumericEditField
        LYKAEditFieldLabel              matlab.ui.control.Label
        LXALEditField                   matlab.ui.control.NumericEditField
        LXALEditFieldLabel              matlab.ui.control.Label
        RollingResistanceEditField      matlab.ui.control.NumericEditField
        RollingResistanceEditFieldLabel  matlab.ui.control.Label
        LMUYSlider                      matlab.ui.control.Slider
        LMUYSliderLabel                 matlab.ui.control.Label
        LMUXSlider                      matlab.ui.control.Slider
        LMUXSliderLabel                 matlab.ui.control.Label
        p_tirePaEditField               matlab.ui.control.NumericEditField
        p_tirePaEditFieldLabel          matlab.ui.control.Label
        J_TirekgmEditField              matlab.ui.control.NumericEditField
        J_TirekgmEditFieldLabel         matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        TrackrearmmEditField            matlab.ui.control.NumericEditField
        TrackrearmmEditFieldLabel       matlab.ui.control.Label
        wheelLoadRearEditField          matlab.ui.control.NumericEditField
        wheelLoadRearEditFieldLabel     matlab.ui.control.Label
        CamberEditField                 matlab.ui.control.NumericEditField
        CamberEditFieldLabel            matlab.ui.control.Label
        TrackfrontmmEditField           matlab.ui.control.NumericEditField
        TrackfrontmmEditFieldLabel      matlab.ui.control.Label
        WheelbasemmEditField            matlab.ui.control.NumericEditField
        WheelbasemmEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.wheelbase = app.WheelbasemmEditField.Value;
            app.CallingApp.track = app.TrackfrontmmEditField.Value;
            app.CallingApp.J_Tire = app.J_TirekgmEditField.Value;
            app.CallingApp.p_Tire = app.p_tirePaEditField.Value;
            app.CallingApp.LMUX = app.LMUXSlider.Value;
            app.CallingApp.LMUY = app.LMUYSlider.Value;
            app.CallingApp.LXAL = app.LXALEditField.Value;
            app.CallingApp.LYKA = app.LYKAEditField.Value;
            app.CallingApp.RBX3 = app.RBX3EditField.Value;
            app.CallingApp.k_R = app.RollingResistanceEditField.Value;
            app.CallingApp.FB = app.BrakeForceNEditField.Value;
            app.CallingApp.camber = app.CamberEditField.Value;
            app.CallingApp.m_ph = app.wheelLoadRearEditField.Value;
            
            if app.ABSSwitch.Value == "On"
                app.CallingApp.brakeBias = -1;
            else
                app.CallingApp.brakeBias = app.BrakeBiasFrontEditField.Value;
            end
            
        end
        
        function loadApp(app)
            % Loads data from main app
            app.WheelbasemmEditField.Value = app.CallingApp.wheelbase;
            app.TrackfrontmmEditField.Value = app.CallingApp.track;
            app.J_TirekgmEditField.Value = app.CallingApp.J_Tire;
            app.p_tirePaEditField.Value = app.CallingApp.p_Tire;
            app.LMUXSlider.Value = app.CallingApp.LMUX;
            app.LMUYSlider.Value = app.CallingApp.LMUY;
            app.LXALEditField.Value = app.CallingApp.LXAL;
            app.LYKAEditField.Value = app.CallingApp.LYKA;
            app.RBX3EditField.Value = app.CallingApp.RBX3;
            app.RollingResistanceEditField.Value = app.CallingApp.k_R;
            app.BrakeForceNEditField.Value = app.CallingApp.FB;
            app.CamberEditField.Value = app.CallingApp.camber;
            app.wheelLoadRearEditField.Value = app.CallingApp.m_ph;           
            
            if (app.CallingApp.brakeBias == -1)
                app.ABSSwitch.Value = 'On';
                app.BrakeBiasFrontEditField.Enable = 'off';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'off';
                app.BrakeBiasFrontEditField.Value = 50;
            else
                app.ABSSwitch.Value = 'Off';
                app.BrakeBiasFrontEditField.Enable = 'on';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'on';
                app.BrakeBiasFrontEditField.Value = app.CallingApp.brakeBias;
            end
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)       
%                 PrimaryColor = app.CallingApp.PrimaryColor;
                OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
                PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
%                 OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
%                 SecondaryColor = app.CallingApp.SecondaryColor;
%                 OnSecondaryColor = app.CallingApp.OnSecondaryColor;
                BackgroundColor = app.CallingApp.BackgroundColor;
                OnBackgroundColor = app.CallingApp.OnBackgroundColor;
                SurfaceColor = app.CallingApp.SurfaceColor;
                OnSurfaceColor = app.CallingApp.OnSurfaceColor;
%                 ErrorColor = app.CallingApp.ErrorColor;
%                 OnErrorColor = app.CallingApp.OnErrorColor;
                BorderType = app.CallingApp.BorderType;                 
%                 Host_Logo = app.CallingApp.Host_Logo;
                
                app.SuspensionSetupUIFigure.Color = BackgroundColor;
                app.GeneralPanel.BackgroundColor = SurfaceColor;
                app.GeneralPanel.ForegroundColor = OnSurfaceColor;
                app.GeneralPanel.BorderType = BorderType;
                
                app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
                
                app.WheelbasemmEditFieldLabel.FontColor = OnPrimaryColor;
                app.WheelbasemmEditField.BackgroundColor = SurfaceColor;
                app.WheelbasemmEditField.FontColor = OnSurfaceColor;
                
                app.TrackfrontmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.TrackfrontmmEditField.BackgroundColor = SurfaceColor;
                app.TrackfrontmmEditField.FontColor = OnSurfaceColor;

                app.TrackrearmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.TrackrearmmEditField.BackgroundColor = SurfaceColor;
                app.TrackrearmmEditField.FontColor = OnSurfaceColor;
                
                app.CamberEditFieldLabel.FontColor = OnPrimaryColor;
                app.CamberEditField.BackgroundColor = SurfaceColor;
                app.CamberEditField.FontColor = OnSurfaceColor;
                
                app.wheelLoadRearEditFieldLabel.FontColor = OnPrimaryColor;
                app.wheelLoadRearEditField.BackgroundColor = SurfaceColor;
                app.wheelLoadRearEditField.FontColor = OnSurfaceColor;
                
                app.TyreParametersPanel.BackgroundColor = SurfaceColor;
                app.TyreParametersPanel.ForegroundColor = OnSurfaceColor;
                app.TyreParametersPanel.BorderType = BorderType;
                
                app.J_TirekgmEditFieldLabel.FontColor = OnPrimaryColor;
                app.J_TirekgmEditField.BackgroundColor = SurfaceColor;
                app.J_TirekgmEditField.FontColor = OnSurfaceColor;
                
                app.p_tirePaEditFieldLabel.FontColor = OnPrimaryColor;
                app.p_tirePaEditField.BackgroundColor = SurfaceColor;
                app.p_tirePaEditField.FontColor = OnSurfaceColor;
                
                app.LMUXSliderLabel.FontColor = OnPrimaryColor;
                app.LMUXSlider.FontColor = OnPrimaryColor;
                
                app.LMUYSliderLabel.FontColor = OnPrimaryColor;
                app.LMUYSlider.FontColor = OnPrimaryColor;
                
                app.LXALEditFieldLabel.FontColor = OnPrimaryColor;
                app.LXALEditField.BackgroundColor = SurfaceColor;
                app.LXALEditField.FontColor = OnSurfaceColor;
                
                app.LYKAEditFieldLabel.FontColor = OnPrimaryColor;
                app.LYKAEditField.BackgroundColor = SurfaceColor;
                app.LYKAEditField.FontColor = OnSurfaceColor;
                
                app.RBX3EditFieldLabel.FontColor = OnPrimaryColor;
                app.RBX3EditField.BackgroundColor = SurfaceColor;
                app.RBX3EditField.FontColor = OnSurfaceColor;
                
                app.RollingResistanceEditFieldLabel.FontColor = OnPrimaryColor;
                app.RollingResistanceEditField.BackgroundColor = SurfaceColor;
                app.RollingResistanceEditField.FontColor = OnSurfaceColor;
                
                app.BrakeForceNEditFieldLabel.FontColor = OnPrimaryColor;
                app.BrakeForceNEditField.BackgroundColor = SurfaceColor;
                app.BrakeForceNEditField.FontColor = OnSurfaceColor;
                
                app.BrakesPanel.BackgroundColor = SurfaceColor;
                app.BrakesPanel.ForegroundColor = OnSurfaceColor;
                app.BrakesPanel.BorderType = BorderType;               
                
                app.OutereffectivebrakediscdiametermmEditFieldLabel.FontColor = OnPrimaryColor;
                app.OutereffectivebrakediscdiametermmEditField.BackgroundColor = SurfaceColor;
                app.OutereffectivebrakediscdiametermmEditField.FontColor = OnSurfaceColor;
                
                app.InnereffectivebrakediscdiametermmEditFieldLabel.FontColor = OnPrimaryColor;
                app.InnereffectivebrakediscdiametermmEditField.BackgroundColor = SurfaceColor;
                app.InnereffectivebrakediscdiametermmEditField.FontColor = OnSurfaceColor;
                
                app.BrakeBiasFrontEditFieldLabel.FontColor = OnPrimaryColor;
                app.BrakeBiasFrontEditField.BackgroundColor = SurfaceColor;
                app.BrakeBiasFrontEditField.FontColor = OnSurfaceColor;
                
                app.ABSSwitch.FontColor = OnPrimaryColor;
                
                app.ABSPanel.BackgroundColor = SurfaceColor;
                app.ABSPanel.ForegroundColor = OnSurfaceColor;
                app.ABSPanel.BorderType = BorderType;
                
                app.ResetButton.FontColor = OnPrimaryColor;
                app.ResetButton.BackgroundColor = PrimaryVariantColor;
                
                app.SaveButton.FontColor = OnPrimaryColor;
                app.SaveButton.BackgroundColor = PrimaryVariantColor;

                app.SteeringPanel.BackgroundColor = SurfaceColor;
                app.SteeringPanel.ForegroundColor = OnSurfaceColor;
                app.SteeringPanel.BorderType = BorderType;

                app.SteeringWheelangleEditFieldLabel.FontColor = OnPrimaryColor;
                app.SteeringWheelangleEditField.BackgroundColor = SurfaceColor;
                app.SteeringWheelangleEditField.FontColor = OnSurfaceColor;

                app.SteeringWheelRadiusmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.SteeringWheelRadiusmmEditField.BackgroundColor = SurfaceColor;
                app.SteeringWheelRadiusmmEditField.FontColor = OnSurfaceColor;

                app.SteeringLeverLengthmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.SteeringLeverLengthmmEditField.BackgroundColor = SurfaceColor;
                app.SteeringLeverLengthmmEditField.FontColor = OnSurfaceColor;

                app.PiniondiamatermmEditFieldLabel.FontColor = OnPrimaryColor;
                app.PiniondiamatermmEditField.BackgroundColor = SurfaceColor;
                app.PiniondiamatermmEditField.FontColor = OnSurfaceColor;

                app.AckermannEditFieldLabel.FontColor = OnPrimaryColor;
                app.AckermannEditField.BackgroundColor = SurfaceColor;
                app.AckermannEditField.FontColor = OnSurfaceColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadApp(app);
            
            changeColorScheme(app);
        end

        % Close request function: SuspensionSetupUIFigure
        function SuspensionSetupUIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Suspension Options button while dialog is open
            app.CallingApp.SuspensionButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadApp(app);
        end

        % Value changed function: ABSSwitch
        function ABSSwitchValueChanged(app, event)

            if app.ABSSwitch.Value == "Off"
                app.BrakeBiasFrontEditField.Enable = 'on';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'on';
            else
                app.BrakeBiasFrontEditField.Enable = 'off';
                app.BrakeBiasFrontEditFieldLabel.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuspensionSetupUIFigure = uifigure('Visible', 'off');
            app.SuspensionSetupUIFigure.Name = 'Suspension Setup';
            app.SuspensionSetupUIFigure.Icon = 'Baltic.png';
            app.SuspensionSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @SuspensionSetupUIFigureCloseRequest, true);
            app.GeneralPanel = uipanel(app.SuspensionSetupUIFigure);
            app.GeneralPanel.Title = 'General';
            app.WheelbasemmEditFieldLabel = uilabel(app.GeneralPanel);
            app.WheelbasemmEditFieldLabel.Text = 'Wheelbase [mm]';
            app.WheelbasemmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.WheelbasemmEditField.LowerLimitInclusive = 'off';
            app.WheelbasemmEditField.Limits = [0 Inf];
            app.WheelbasemmEditField.Value = 1;
            app.TrackfrontmmEditFieldLabel = uilabel(app.GeneralPanel);
            app.TrackfrontmmEditFieldLabel.Text = 'Track front [mm]';
            app.TrackfrontmmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TrackfrontmmEditField.LowerLimitInclusive = 'off';
            app.TrackfrontmmEditField.Limits = [0 Inf];
            app.TrackfrontmmEditField.Value = 1;
            app.CamberEditFieldLabel = uilabel(app.GeneralPanel);
            app.CamberEditFieldLabel.Text = 'Camber [°]';
            app.CamberEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.CamberEditField.LowerLimitInclusive = 'off';
            app.CamberEditField.UpperLimitInclusive = 'off';
            app.wheelLoadRearEditFieldLabel = uilabel(app.GeneralPanel);
            app.wheelLoadRearEditFieldLabel.Text = 'wheel Load Rear [%]';
            app.wheelLoadRearEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TrackrearmmEditFieldLabel = uilabel(app.GeneralPanel);
            app.TrackrearmmEditFieldLabel.Text = 'Track rear [mm]';
            app.TrackrearmmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TrackrearmmEditField.LowerLimitInclusive = 'off';
            app.TrackrearmmEditField.Limits = [0 Inf];
            app.TrackrearmmEditField.Value = 1;
            app.TyreParametersPanel = uipanel(app.SuspensionSetupUIFigure);
            app.TyreParametersPanel.Title = 'Tyre Parameters';
            app.J_TirekgmEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.J_TirekgmEditFieldLabel.Text = 'J_Tire [kg*m²]';
            app.J_TirekgmEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.J_TirekgmEditField.LowerLimitInclusive = 'off';
            app.J_TirekgmEditField.UpperLimitInclusive = 'off';
            app.J_TirekgmEditField.Limits = [0 Inf];
            app.J_TirekgmEditField.Value = 1;
            app.p_tirePaEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.p_tirePaEditFieldLabel.Text = 'p_tire [Pa]';
            app.p_tirePaEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.p_tirePaEditField.LowerLimitInclusive = 'off';
            app.p_tirePaEditField.UpperLimitInclusive = 'off';
            app.p_tirePaEditField.Limits = [0 Inf];
            app.p_tirePaEditField.ValueDisplayFormat = '%.0f';
            app.p_tirePaEditField.Value = 1;
            app.LMUXSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUXSliderLabel.Text = 'LMUX';
            app.LMUXSlider = uislider(app.TyreParametersPanel);
            app.LMUXSlider.Limits = [0.6 0.75];
            app.LMUXSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUXSlider.Value = 0.65;
            app.LMUYSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUYSliderLabel.Text = 'LMUY';
            app.LMUYSlider = uislider(app.TyreParametersPanel);
            app.LMUYSlider.Limits = [0.6 0.75];
            app.LMUYSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUYSlider.Value = 0.65;
            app.RollingResistanceEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.RollingResistanceEditFieldLabel.Text = 'Rolling Resistance [-]';
            app.RollingResistanceEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.RollingResistanceEditField.Limits = [0 Inf];
            app.RollingResistanceEditField.ValueDisplayFormat = '%.3f';
            app.LXALEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.LXALEditFieldLabel.Text = 'LXAL [-]';
            app.LXALEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.LXALEditField.Limits = [0 Inf];
            app.LXALEditField.ValueDisplayFormat = '%.3f';
            app.LYKAEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.LYKAEditFieldLabel.Text = 'LYKA [-]';
            app.LYKAEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.LYKAEditField.Limits = [0 Inf];
            app.LYKAEditField.ValueDisplayFormat = '%.3f';
            app.RBX3EditFieldLabel = uilabel(app.TyreParametersPanel);
            app.RBX3EditFieldLabel.Text = 'RBX3 [-]';
            app.RBX3EditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.RBX3EditField.Limits = [0 Inf];
            app.RBX3EditField.ValueDisplayFormat = '%.3f';
            app.SaveButton = uibutton(app.SuspensionSetupUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.SuspensionSetupUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.SuspensionSetupUIFigure);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.BrakesPanel = uipanel(app.SuspensionSetupUIFigure);
            app.BrakesPanel.Title = 'Brakes';
            app.OutereffectivebrakediscdiametermmEditFieldLabel = uilabel(app.BrakesPanel);
            app.OutereffectivebrakediscdiametermmEditFieldLabel.Text = 'Outer effective brake disc diameter [mm]';
            app.OutereffectivebrakediscdiametermmEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.OutereffectivebrakediscdiametermmEditField.LowerLimitInclusive = 'off';
            app.OutereffectivebrakediscdiametermmEditField.UpperLimitInclusive = 'off';
            app.OutereffectivebrakediscdiametermmEditField.Limits = [0 Inf];
            app.OutereffectivebrakediscdiametermmEditField.ValueDisplayFormat = '%.0f';
            app.OutereffectivebrakediscdiametermmEditField.Value = 1;
            app.InnereffectivebrakediscdiametermmEditFieldLabel = uilabel(app.BrakesPanel);
            app.InnereffectivebrakediscdiametermmEditFieldLabel.Text = 'Inner effective brake disc diameter [mm]';
            app.InnereffectivebrakediscdiametermmEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.InnereffectivebrakediscdiametermmEditField.LowerLimitInclusive = 'off';
            app.InnereffectivebrakediscdiametermmEditField.UpperLimitInclusive = 'off';
            app.InnereffectivebrakediscdiametermmEditField.Limits = [0 Inf];
            app.InnereffectivebrakediscdiametermmEditField.ValueDisplayFormat = '%.0f';
            app.InnereffectivebrakediscdiametermmEditField.Value = 1;
            app.BrakeForceNEditFieldLabel = uilabel(app.BrakesPanel);
            app.BrakeForceNEditFieldLabel.Text = 'Brake Force [N]';
            app.BrakeForceNEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.BrakeForceNEditField.UpperLimitInclusive = 'off';
            app.BrakeForceNEditField.Limits = [0 Inf];
            app.BrakeForceNEditField.ValueDisplayFormat = '%.0f';
            app.ABSPanel = uipanel(app.BrakesPanel);
            app.ABSPanel.Title = 'ABS';
            app.BrakeBiasFrontEditFieldLabel = uilabel(app.ABSPanel);
            app.BrakeBiasFrontEditFieldLabel.Text = 'Brake Bias Front [%]';
            app.BrakeBiasFrontEditField = uieditfield(app.ABSPanel, 'numeric');
            app.BrakeBiasFrontEditField.Limits = [0 100];
            app.BrakeBiasFrontEditField.ValueDisplayFormat = '%.0f';
            app.BrakeBiasFrontEditField.Value = 1;
            app.ABSSwitch = uiswitch(app.ABSPanel, 'slider');
            app.ABSSwitch.ValueChangedFcn = createCallbackFcn(app, @ABSSwitchValueChanged, true);
            app.Image2 = uiimage(app.SuspensionSetupUIFigure);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            app.SteeringPanel = uipanel(app.SuspensionSetupUIFigure);
            app.SteeringPanel.Title = 'Steering';
            app.SteeringWheelRadiusmmEditFieldLabel = uilabel(app.SteeringPanel);
            app.SteeringWheelRadiusmmEditFieldLabel.Text = 'Steering Wheel Radius [mm]';
            app.SteeringWheelRadiusmmEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringWheelRadiusmmEditField.Value = 110;
            app.SteeringLeverLengthmmEditFieldLabel = uilabel(app.SteeringPanel);
            app.SteeringLeverLengthmmEditFieldLabel.Text = 'Steering Lever Length [mm]';
            app.SteeringLeverLengthmmEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringLeverLengthmmEditField.Value = 60.95;
            app.SteeringWheelangleEditFieldLabel = uilabel(app.SteeringPanel);
            app.SteeringWheelangleEditFieldLabel.Text = 'Steering Wheel angle [°]';
            app.SteeringWheelangleEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringWheelangleEditField.LowerLimitInclusive = 'off';
            app.SteeringWheelangleEditField.UpperLimitInclusive = 'off';
            app.SteeringWheelangleEditField.Limits = [0 Inf];
            app.SteeringWheelangleEditField.Tooltip = {'Complete steering wheel angle from full left to full right lock.'};
            app.SteeringWheelangleEditField.Value = 220;
            app.PiniondiamatermmEditFieldLabel = uilabel(app.SteeringPanel);
            app.PiniondiamatermmEditFieldLabel.Text = 'Pinion diamater [mm]';
            app.PiniondiamatermmEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.PiniondiamatermmEditField.Value = 22.5;
            app.AckermannEditFieldLabel = uilabel(app.SteeringPanel);
            app.AckermannEditFieldLabel.Text = 'Ackermann [%]';
            app.AckermannEditField = uieditfield(app.SteeringPanel, 'numeric');
            % Show the figure after all components are created
            app.SuspensionSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Suspension(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SuspensionSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuspensionSetupUIFigure)
        end
    end
end